classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EncryptyourimageshereLabel  matlab.ui.control.Label
        DarkLightSwitch             matlab.ui.control.Switch
        DarkLightSwitchLabel        matlab.ui.control.Label
        OptionsButton               matlab.ui.control.Button
        DecryptionButton            matlab.ui.control.Button
        EncyptionButton             matlab.ui.control.Button
        WelcometoXEncryptionServicesLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

        end

        % Button pushed function: EncyptionButton
        function EncyptionButtonPushed(app, event)
            [fileName] = uigetfile({'*.*,*.png,*.jpg'},'Select a image to encrypt');
            ImageFile = imread(fileName);
            imagesc(app.UIAxes, ImageFile);
        end

        % Button pushed function: DecryptionButton
        function DecryptionButtonPushed(app, event)
            [FileName, PathName] = uigetfile({'*.*,*.png,*.jpg'},'Select a image to decrypt');
            imshow(fullfile(PathName, FileName), 'Parent', handles.axes1);
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            optionsMenu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoXEncryptionServicesLabel = uilabel(app.UIFigure);
            app.WelcometoXEncryptionServicesLabel.Text = 'Welcome to X Encryption Services!';
            app.EncyptionButton = uibutton(app.UIFigure, 'push');
            app.EncyptionButton.ButtonPushedFcn = createCallbackFcn(app, @EncyptionButtonPushed, true);
            app.EncyptionButton.Text = 'Encyption';
            app.DecryptionButton = uibutton(app.UIFigure, 'push');
            app.DecryptionButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptionButtonPushed, true);
            app.DecryptionButton.Text = 'Decryption';
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            app.DarkLightSwitchLabel = uilabel(app.UIFigure);
            app.DarkLightSwitchLabel.Text = 'Dark/Light';
            app.DarkLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.DarkLightSwitch.Items = {'', ''};
            app.DarkLightSwitch.Value = '';
            app.EncryptyourimageshereLabel = uilabel(app.UIFigure);
            app.EncryptyourimageshereLabel.Text = 'Encrypt your images here.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end